classdef lf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        apertureSliderLabel      matlab.ui.control.Label
        apertureSlider           matlab.ui.control.Slider
        focalsurfaceSliderLabel  matlab.ui.control.Label
        focalsurfaceSlider       matlab.ui.control.Slider
        XSliderLabel             matlab.ui.control.Label
        XSlider                  matlab.ui.control.Slider
        YSliderLabel             matlab.ui.control.Label
        YSlider                  matlab.ui.control.Slider
        ZSliderLabel             matlab.ui.control.Label
        ZSlider                  matlab.ui.control.Slider
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        img_width = 320
        img_height = 240
        imgs = zeros(16, 16, 240, 320, 3)  % 16*16=u,v  320 = s, 240 = t
    end
    
    methods (Access = private)
        
        function img = img_show(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 根据当前属性， 输出240*320*3图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            img = reshape(app.imgs(1, 1, :, :, :), app.img_height, app.img_width, 3);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = 'red';
            
            % 设置默认参数
            app.apertureSlider.Value = 50;
            app.focalsurfaceSlider.Value = 50;
            app.XSlider.Value = 50;
            app.YSlider.Value = 50;
            app.ZSlider.Value = 50;
            
            % 读取图片
            img_path_list = dir('*.bmp');
            for i = 1:16
                for j=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
            disp("数据加载完成");
            
            
            app.Lamp.Color = 'green';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: apertureSlider
        function apertureSliderValueChanged(app, event)
            app.Lamp.Color = 'red';
            value = app.apertureSlider.Value;
            disp(value);
            
            
            img = app.img_show();
            imshow(img, 'Parent',app.UIAxes);
            app.Lamp.Color = 'green';
        end

        % Value changed function: focalsurfaceSlider
        function focalsurfaceSliderValueChanged(app, event)
            value = app.focalsurfaceSlider.Value;
            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.apertureSliderLabel = uilabel(app.LeftPanel);
            app.apertureSliderLabel.Text = 'aperture';
            app.apertureSlider = uislider(app.LeftPanel);
            app.apertureSlider.ValueChangedFcn = createCallbackFcn(app, @apertureSliderValueChanged, true);
            app.apertureSlider.Tooltip = {''};
            app.focalsurfaceSliderLabel = uilabel(app.LeftPanel);
            app.focalsurfaceSliderLabel.Text = 'focal surface';
            app.focalsurfaceSlider = uislider(app.LeftPanel);
            app.focalsurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @focalsurfaceSliderValueChanged, true);
            app.XSliderLabel = uilabel(app.LeftPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.LeftPanel);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.LeftPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.LeftPanel);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.LeftPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.LeftPanel);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '320 x 240')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '处理中';
            app.Lamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end